classdef myGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        DodajdaneButton            matlab.ui.control.Button
        GodzinaEditFieldLabel      matlab.ui.control.Label
        GodzinaEditField           matlab.ui.control.NumericEditField
        IlostopniEditFieldLabel    matlab.ui.control.Label
        IlostopniEditField         matlab.ui.control.NumericEditField
        ZnajdztemeperatureButton   matlab.ui.control.Button
        GodzinaEditField_2Label    matlab.ui.control.Label
        GodzinaEditField_2         matlab.ui.control.NumericEditField
        TemperaturaotejgodziniewynosiaLabel  matlab.ui.control.Label
        XstopniLabel               matlab.ui.control.Label
        ZnajdzgodzineButton        matlab.ui.control.Button
        TemperaturaEditFieldLabel  matlab.ui.control.Label
        TemperaturaEditField       matlab.ui.control.NumericEditField
        godzinaXLabel              matlab.ui.control.Label
        RightPanel                 matlab.ui.container.Panel
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        xw = [5,6,8,11];
        yw = [-2,3,7,10];
        x = [];
        W = [];
    end
    
    methods (Access = private)
        
        function createAxes(app)
            N = size(app.xw,2);
            A = zeros(N);
            app.x = app.xw(1):1/10000:app.xw(end);
            A(:,1) = app.yw';
            for i=2:N
                for j=2:i
                    A(i,j) = (A(i,j-1)-A(i-1,j-1))/(app.xw(i)-app.xw(i-j+1));
                end
            end
            app.W = A(1,1);
            for i=2:N
                buf = A(i,i);
                for j=2:i
                    buf = buf.*(app.x-app.xw(j-1));
                end
                app.W = app.W+buf;
            end
            cla(app.UIAxes,'reset')
            plot(app.UIAxes, app.xw, app.yw, 'r*');
            hold(app.UIAxes, 'on')
            plot(app.UIAxes, app.x, app.W)
        end
        
        function addTempData(app, hour, temp)
            for i=1:size(app.xw,2)
                if app.xw(i) == hour
                    return;
                end
                if app.xw(i) > hour
                    app.xw = [app.xw(1:i-1), hour, app.xw(i:end)];
                    app.yw = [app.yw(1:i-1), temp, app.yw(i:end)];
                    createAxes(app);
                    return;
                end
            end
            app.xw = [app.xw, hour];
            app.yw = [app.yw, temp];
            createAxes(app);
        end
        
        function hour = findHour(app, temp)
            index = find(app.W == temp);
            hour = app.x(index);
        end
       
        function temp = findTemp(app, hour)
            index = find(app.x == hour);
            temp = app.W(index);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            createAxes(app)
        end

        % Button pushed function: DodajdaneButton
        function DodajdaneButtonPushed(app, event)
            temp = app.IlostopniEditField.Value;
            hour = app.GodzinaEditField.Value;
            
            if hour > 0 && hour < 24
                addTempData(app, hour, temp)
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: ZnajdztemeperatureButton
        function ZnajdztemeperatureButtonPushed(app, event)
            hour = app.GodzinaEditField_2.Value;
            result = findTemp(app,hour);
            if isempty(result)
                result = "Nie znaleziono.";
            else
                result = result + " stopni";
            end
            app.XstopniLabel.Text = result;
        end

        % Button pushed function: ZnajdzgodzineButton
        function ZnajdzgodzineButtonPushed(app, event)
            temp = app.TemperaturaEditField.Value;
            result = findHour(app,temp);
            if isempty(result)
                result = "Nie znaleziono.";
            else
                result = "godzina "+result;
            end
            app.godzinaXLabel.Text = result;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DodajdaneButton = uibutton(app.LeftPanel, 'push');
            app.DodajdaneButton.ButtonPushedFcn = createCallbackFcn(app, @DodajdaneButtonPushed, true);
            app.DodajdaneButton.Text = 'Dodaj dane';
            app.GodzinaEditFieldLabel = uilabel(app.LeftPanel);
            app.GodzinaEditFieldLabel.Text = 'Godzina';
            app.GodzinaEditField = uieditfield(app.LeftPanel, 'numeric');
            app.IlostopniEditFieldLabel = uilabel(app.LeftPanel);
            app.IlostopniEditFieldLabel.Text = 'Ilość stopni';
            app.IlostopniEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ZnajdztemeperatureButton = uibutton(app.LeftPanel, 'push');
            app.ZnajdztemeperatureButton.ButtonPushedFcn = createCallbackFcn(app, @ZnajdztemeperatureButtonPushed, true);
            app.ZnajdztemeperatureButton.Text = 'Znajdz temeperature';
            app.GodzinaEditField_2Label = uilabel(app.LeftPanel);
            app.GodzinaEditField_2Label.Text = 'Godzina';
            app.GodzinaEditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.TemperaturaotejgodziniewynosiaLabel = uilabel(app.LeftPanel);
            app.TemperaturaotejgodziniewynosiaLabel.Text = 'Temperatura o tej godzinie wynosiła';
            app.XstopniLabel = uilabel(app.LeftPanel);
            app.XstopniLabel.Text = 'X stopni';
            app.ZnajdzgodzineButton = uibutton(app.LeftPanel, 'push');
            app.ZnajdzgodzineButton.ButtonPushedFcn = createCallbackFcn(app, @ZnajdzgodzineButtonPushed, true);
            app.ZnajdzgodzineButton.Text = 'Znajdz godzine';
            app.godzinaXLabel = uilabel(app.LeftPanel);
            app.godzinaXLabel.Text = 'godzina: X';
            app.TemperaturaEditFieldLabel = uilabel(app.LeftPanel);
            app.TemperaturaEditFieldLabel.Text = 'Temperatura';
            app.TemperaturaEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, {'Wykres'; ''})
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = myGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end